classdef lidarGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        StichPointCloudsButton         matlab.ui.control.Button
        DetectObjectsButton            matlab.ui.control.Button
        CurrentFileLabel               matlab.ui.control.Label
        PointCloudFilename             matlab.ui.control.Label
        SelectPointCloudFilecsvButton  matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        csvPath % Path to CSV file
        DialogApp % dialog box
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectPointCloudFilecsvButton
        function SelectPointCloudFilecsvButtonPushed(app, event)
            app.PointCloudFilename.Text = uigetfile("../*.csv");
        end

        % Button pushed function: DetectObjectsButton
        function DetectObjectsButtonPushed(app, event)
            if (strcmp(app.PointCloudFilename.Text, "No File Selected"))
                app.DialogApp = ErrorSelectFileDialog;
                return;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {216, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectPointCloudFilecsvButton = uibutton(app.LeftPanel, 'push');
            app.SelectPointCloudFilecsvButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointCloudFilecsvButtonPushed, true);
            app.SelectPointCloudFilecsvButton.Text = 'Select Point Cloud File (.csv)';
            app.PointCloudFilename = uilabel(app.LeftPanel);
            app.PointCloudFilename.Text = 'No File Selected';
            app.CurrentFileLabel = uilabel(app.LeftPanel);
            app.CurrentFileLabel.Text = 'Current File';
            app.DetectObjectsButton = uibutton(app.LeftPanel, 'push');
            app.DetectObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectObjectsButtonPushed, true);
            app.DetectObjectsButton.Text = 'Detect Objects';
            app.StichPointCloudsButton = uibutton(app.LeftPanel, 'push');
            app.StichPointCloudsButton.Text = 'Stich Point Clouds';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lidarGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end