classdef lidarMainGui_REMOTE_658 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DetectobjectsinthepointcloudsviaPointPillarsMLLabel  matlab.ui.control.Label
        CreateastitchedscenefromthepointcloudsLabel  matlab.ui.control.Label
        WelcometoLivoxLidarLabellerTobeginselectacsvfileconvertedfrom  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        StichPointCloudsButton          matlab.ui.control.Button
        DetectObjectsButton             matlab.ui.control.Button
        SelectPointCloudsFilecsvButton  matlab.ui.control.Button
        PointCloudFilename              matlab.ui.control.Label
        CurrentFileLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        csvPath   % Path to current csv file
        csvFile   % Current Csv File
        DialogApp % Opened Dialog box
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectPointCloudsFilecsvButton
        function SelectPointCloudsFilecsvButtonPushed(app, event)

            [file, path] = uigetfile("../*.csv");
            [app.csvFile, app.csvPath] = convertCharsToStrings(file, path);
            try 
            app.PointCloudFilename.Text = app.csvPath + app.csvFile;
            catch  ME
            end
        end

        % Button pushed function: StichPointCloudsButton
        function StichPointCloudsButtonPushed(app, event)
            app.StichPointCloudsButton.Enable = false;
            app.DetectObjectsButton.Enable = false;
            app.SelectPointCloudsFilecsvButton.Enable = false;
            if (strcmp(app.PointCloudFilename.Text, "No File Selected"))
                app.DialogApp = ErrorSelectFileDialog;
                app.StichPointCloudsButton.Enable = true;
                app.DetectObjectsButton.Enable = true;
                app.SelectPointCloudsFilecsvButton.Enable = true;
                return;
            end           
            pointCloud = pointCloudStitch(app.csvPath + app.csvFile);      
            pcshow(pointCloud);
            app.StichPointCloudsButton.Enable = true;
            app.DetectObjectsButton.Enable = true;
            app.SelectPointCloudsFilecsvButton.Enable = true;
        end

        % Button pushed function: DetectObjectsButton
        function DetectObjectsButtonPushed(app, event)
            app.StichPointCloudsButton.Enable = false;
            app.DetectObjectsButton.Enable = false;
            app.SelectPointCloudsFilecsvButton.Enable = false;
            if (strcmp(app.PointCloudFilename.Text, "No File Selected"))
                app.DialogApp = ErrorSelectFileDialog;
                app.StichPointCloudsButton.Enable = true;
                app.DetectObjectsButton.Enable = true;
                app.SelectPointCloudsFilecsvButton.Enable = true;
                return;
            end
            pp;
            app.StichPointCloudsButton.Enable = true;
            app.DetectObjectsButton.Enable = true;
            app.SelectPointCloudsFilecsvButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CurrentFileLabel = uilabel(app.UIFigure);
            app.CurrentFileLabel.Text = 'Current File:';
            app.PointCloudFilename = uilabel(app.UIFigure);
            app.PointCloudFilename.Text = 'No File Selected';
            app.SelectPointCloudsFilecsvButton = uibutton(app.UIFigure, 'push');
            app.SelectPointCloudsFilecsvButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointCloudsFilecsvButtonPushed, true);
            app.SelectPointCloudsFilecsvButton.Text = 'Select Point Clouds File (.csv)';
            app.DetectObjectsButton = uibutton(app.UIFigure, 'push');
            app.DetectObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectObjectsButtonPushed, true);
            app.DetectObjectsButton.Text = 'Detect Objects';
            app.StichPointCloudsButton = uibutton(app.UIFigure, 'push');
            app.StichPointCloudsButton.ButtonPushedFcn = createCallbackFcn(app, @StichPointCloudsButtonPushed, true);
            app.StichPointCloudsButton.Text = 'Stich Point Clouds';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.WelcometoLivoxLidarLabellerTobeginselectacsvfileconvertedfrom = uilabel(app.UIFigure);
            app.WelcometoLivoxLidarLabellerTobeginselectacsvfileconvertedfrom.Text = 'Welcome to Livox Lidar Labeller. To begin select a .csv file converted from .lvx in the Livox Viewer';
            app.CreateastitchedscenefromthepointcloudsLabel = uilabel(app.UIFigure);
            app.CreateastitchedscenefromthepointcloudsLabel.Text = 'Create a stitched scene from the point clouds';
            app.DetectobjectsinthepointcloudsviaPointPillarsMLLabel = uilabel(app.UIFigure);
            app.DetectobjectsinthepointcloudsviaPointPillarsMLLabel.Text = 'Detect objects in the point clouds via Point Pillars ML';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lidarMainGui_REMOTE_658

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end